classdef FunctionPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UserMessageLabel      matlab.ui.control.Label
        OutputPanel           matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        PlotButton            matlab.ui.control.Button
        FunctionPlotterLabel  matlab.ui.control.Label
        InputsPanel           matlab.ui.container.Panel
        XmaxEditField         matlab.ui.control.NumericEditField
        XmaxEditFieldLabel    matlab.ui.control.Label
        XminEditField         matlab.ui.control.NumericEditField
        XminEditFieldLabel    matlab.ui.control.Label
        FunctionEditField     matlab.ui.control.EditField
        FunctionLabel         matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                             % Read Inputs Section %
            
            %Take Input Function from user as string
            Function_string = app.FunctionEditField.Value;
            
            %Calculate Length of Function String
            Function_string_length = length(Function_string);
            
            %Take Xmin input from user
            Xmin = app.XminEditField.Value;
            
            %Take Xmax input fom user
            Xmax = app.XmaxEditField.Value;
            
                             %  Ascii Code Constants %
            Ascii_Codes_Numbers = [double('0'):1:double('9')];                 
            Ascii_Codes = [double('x') double('+') double('-') double('*') double('/') double('.') double('^') Ascii_Codes_Numbers ];  
            Ascii_Codes_Length = length(Ascii_Codes);

            
                             % Error Check Technique % 
                            
            Flag = 0 ;
            %Check that each character in the string belongs to the array
            %Non-Equlaity means invalid symbol entry.
            
            for counter = 1:Function_string_length
                
               for counter2 = 1:Ascii_Codes_Length
                   
                   if(double(Function_string(counter)) == Ascii_Codes(counter2) )
                       Flag = 1;
                       break;
                   end
                   
               end
                
            end

            
            %Wrong Input Character
            if(Flag == 0)
                
            %Error Plotting Message to the user
            plot(app.UIAxes,0,0);
            app.UserMessageLabel.Text = "Error! Invalid Input Function.";
            app.UserMessageLabel.FontColor = [1.00,0.00,0.00]; 
            
            else
             
                             % App Logic Section %   
            
            %Convert the String function
            Output.Yaxis = inline(Function_string);
            
            %Limit the x axis
            Output.Xaxis = Xmin : 0.1 : Xmax ;
            
            %Plot the Function
            plot(app.UIAxes,Output.Xaxis , Output.Yaxis(Output.Xaxis));
            xlim(app.UIAxes,[Xmin Xmax]);
            
            %Successfull Plotting Message to the user
            app.UserMessageLabel.Text = "Function is Plotted Successfully!";
            app.UserMessageLabel.FontColor = [0.39,0.83,0.07];    
            
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.FunctionLabel = uilabel(app.InputsPanel);
            app.FunctionLabel.Text = 'Function   ';
            app.FunctionEditField = uieditfield(app.InputsPanel, 'text');
            app.XminEditFieldLabel = uilabel(app.InputsPanel);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.InputsPanel, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.InputsPanel);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.InputsPanel, 'numeric');
            app.FunctionPlotterLabel = uilabel(app.UIFigure);
            app.FunctionPlotterLabel.Text = 'Function Plotter';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UIAxes = uiaxes(app.OutputPanel);
            title(app.UIAxes, 'Function')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'F(x)')
            zlabel(app.UIAxes, 'Z')
            app.UserMessageLabel = uilabel(app.UIFigure);
            app.UserMessageLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FunctionPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end